classdef Project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UITable                       matlab.ui.control.Table
        ReaddataButton                matlab.ui.control.Button
        EditField                     matlab.ui.control.EditField
        UlstrengthEditFieldLabel      matlab.ui.control.Label
        UlstrengthEditField           matlab.ui.control.NumericEditField
        percentAreaRedEditFieldLabel  matlab.ui.control.Label
        percentAreaRedEditField       matlab.ui.control.NumericEditField
        percentelongationEditFieldLabel  matlab.ui.control.Label
        percentelongationEditField    matlab.ui.control.NumericEditField
        ElasticityEditFieldLabel      matlab.ui.control.Label
        ElasticityEditField           matlab.ui.control.NumericEditField
        ModulusofresilienceEditFieldLabel  matlab.ui.control.Label
        ModulusofresilienceEditField  matlab.ui.control.NumericEditField
        ModulusoftoughnessEditFieldLabel  matlab.ui.control.Label
        ModulusoftoughnessEditField   matlab.ui.control.NumericEditField
        Proportional_limitEditFieldLabel  matlab.ui.control.Label
        Proportional_limitEditField   matlab.ui.control.NumericEditField
        ReduceddiaEditFieldLabel      matlab.ui.control.Label
        ReduceddiaEditField           matlab.ui.control.NumericEditField
        InitialdiaEditField           matlab.ui.control.NumericEditField
        InitialdiaEditFieldLabel      matlab.ui.control.Label
        GagelengthEditField           matlab.ui.control.NumericEditField
        GagelengthEditFieldLabel      matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ReaddataButton
        function ReaddataButtonPushed(app, event)
            t=readtable("Stress1.xlsx","Sheet",1);
            app.UITable.Data= t;
            t.Properties.VariableNames{1}='F';
            t.Properties.VariableNames{2}='y';
            app.UITable.ColumnName=t.Properties.VariableNames;
            x=table2array(t(:,"F"));
            y=table2array(t(:,"y"));
            plot(app.UIAxes,y,x);
             reduceddia=str2double(app.ReduceddiaEditField.Value);
             Initialdia=str2double(app.InitialdiaEditField.Value);
             gagelength=str2double(app.GagelengthEditField.Value);
            
            [Ulstrength,percentAreaRed,percentelongation,Elasticity,modulus,Proportional_limit]=Mild_steel(x,y,@calibration,reduceddia);
            disp(app.ElasticityEditField.Value)=Elasticity;
            app.UlstrengthEditField.Value=Ulstrength;
            disp(app.percentAreaRedEditField.Value)=percentAreaRed;
            app.ModulusofresilienceEditField.Value=modulus(1);
            app.ModulusoftoughnessEditField.Value=modulus(2);
            app.Proportional_limitEditField.Value=Proportional_limit;
            app.percentelongationEditField.Value=percentelongation;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Load'; 'Elongation'; 'Extensometer reading'};
            app.UITable.RowName = {};
            app.ReaddataButton = uibutton(app.UIFigure, 'push');
            app.ReaddataButton.ButtonPushedFcn = createCallbackFcn(app, @ReaddataButtonPushed, true);
            app.ReaddataButton.Text = 'Read data';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Value = '                                         Static Tension Test of MIld Steel Specimen';
            app.UlstrengthEditFieldLabel = uilabel(app.UIFigure);
            app.UlstrengthEditFieldLabel.Text = 'Ulstrength';
            app.UlstrengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.percentAreaRedEditFieldLabel = uilabel(app.UIFigure);
            app.percentAreaRedEditFieldLabel.Text = 'percentAreaRed';
            app.percentAreaRedEditField = uieditfield(app.UIFigure, 'numeric');
            app.percentelongationEditFieldLabel = uilabel(app.UIFigure);
            app.percentelongationEditFieldLabel.Text = 'percentelongation';
            app.percentelongationEditField = uieditfield(app.UIFigure, 'numeric');
            app.ElasticityEditFieldLabel = uilabel(app.UIFigure);
            app.ElasticityEditFieldLabel.Text = 'Elasticity';
            app.ElasticityEditField = uieditfield(app.UIFigure, 'numeric');
            app.ModulusofresilienceEditFieldLabel = uilabel(app.UIFigure);
            app.ModulusofresilienceEditFieldLabel.Text = 'Modulus of resilience';
            app.ModulusofresilienceEditField = uieditfield(app.UIFigure, 'numeric');
            app.ModulusoftoughnessEditFieldLabel = uilabel(app.UIFigure);
            app.ModulusoftoughnessEditFieldLabel.Text = 'Modulus of toughness';
            app.ModulusoftoughnessEditField = uieditfield(app.UIFigure, 'numeric');
            app.Proportional_limitEditFieldLabel = uilabel(app.UIFigure);
            app.Proportional_limitEditFieldLabel.Text = 'Proportional_limit';
            app.Proportional_limitEditField = uieditfield(app.UIFigure, 'numeric');
            app.ReduceddiaEditFieldLabel = uilabel(app.UIFigure);
            app.ReduceddiaEditFieldLabel.Text = 'Reduced dia';
            app.ReduceddiaEditField = uieditfield(app.UIFigure, 'numeric');
            app.InitialdiaEditField = uieditfield(app.UIFigure, 'numeric');
            app.InitialdiaEditFieldLabel = uilabel(app.UIFigure);
            app.InitialdiaEditFieldLabel.Text = 'Initial dia';
            app.GagelengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.GagelengthEditFieldLabel = uilabel(app.UIFigure);
            app.GagelengthEditFieldLabel.Text = 'Gage length';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end